classdef doan_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        NhpphngtrnhdngxgxdnhchophplpEditField  matlab.ui.control.EditField
        NhpphngtrnhdngxgxdnhchophplpEditFieldLabel  matlab.ui.control.Label
        ThchinButton                   matlab.ui.control.Button
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpLabel                     matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        ChnphngphptmButtonGroup        matlab.ui.container.ButtonGroup
        NewtonButton                   matlab.ui.control.ToggleButton
        LpButton                       matlab.ui.control.ToggleButton
        ChiaiButton                    matlab.ui.control.ToggleButton
        NhpsaischophpEditField         matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        NhpkhongphnlynghimabLabel      matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        ThchinButton_2                 matlab.ui.control.Button
        KtqunisuyEditField             matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        NhpgitrcnnisuyEditField        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        ChnphngphpnisuyButtonGroup     matlab.ui.container.ButtonGroup
        LagrangeButton                 matlab.ui.control.ToggleButton
        NewtonButton_2                 matlab.ui.control.ToggleButton
        Nhpdliuyvd123EditField         matlab.ui.control.EditField
        Nhpdliuyvd123EditFieldLabel    matlab.ui.control.Label
        Nhpdliuxvd123EditField         matlab.ui.control.EditField
        Nhpdliuxvd123EditFieldLabel    matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        tinhtoan                       matlab.ui.control.Button
        kqreq                          matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        reqnum                         matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        ketqua                         matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        nhapy                          matlab.ui.control.EditField
        Nhpdliuyvd123EditField_2Label  matlab.ui.control.Label
        nhapx                          matlab.ui.control.EditField
        Nhpdliuxvd123EditField_2Label  matlab.ui.control.Label
        ChnphngphphiquyButtonGroup     matlab.ui.container.ButtonGroup
        Logarit                        matlab.ui.control.ToggleButton
        hammu                          matlab.ui.control.ToggleButton
        tuyentinh                      matlab.ui.control.ToggleButton
        dothi                          matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        ThchinButton_4                 matlab.ui.control.Button
        KtquEditField                  matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        NhpgitrcntnhohmEditField       matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        ChnphngphpohmButtonGroup       matlab.ui.container.ButtonGroup
        TrungtmButton                  matlab.ui.control.ToggleButton
        LiButton                       matlab.ui.control.ToggleButton
        TinButton                      matlab.ui.control.ToggleButton
        ChngitrsaisButtonGroup         matlab.ui.container.ButtonGroup
        Oh2Button                      matlab.ui.control.ToggleButton
        OhButton                       matlab.ui.control.ToggleButton
        Tchvonuchncchnhpdliuth2CheckBox  matlab.ui.control.CheckBox
        NhpbchEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        NhphmsEditField                matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        Nhpdliuyvd123EditField_3       matlab.ui.control.EditField
        Nhpdliuyvd123EditField_3Label  matlab.ui.control.Label
        Nhpdliuxvd123EditField_3       matlab.ui.control.EditField
        Nhpdliuxvd123EditField_3Label  matlab.ui.control.Label
        TichPhanTab                    matlab.ui.container.Tab
        KetQuaEditField_2              matlab.ui.control.EditField
        KtquLabel                      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        NhapNEditField                 matlab.ui.control.EditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        ChonPPButtonGroup              matlab.ui.container.ButtonGroup
        Simpson38Button                matlab.ui.control.RadioButton
        Simpson13Button                matlab.ui.control.RadioButton
        HinhThangButton                matlab.ui.control.RadioButton
        ThucHienButton_5               matlab.ui.control.Button
        NhapCanEditField               matlab.ui.control.EditField
        NhpcntnhTchphnvd15Label        matlab.ui.control.Label
        NhapHamEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label         matlab.ui.control.Label
        HocLabel_2                     matlab.ui.control.Label
        NhapYEditField_4               matlab.ui.control.EditField
        Nhpdliuyvd123Label             matlab.ui.control.Label
        NhapXEditField_4               matlab.ui.control.EditField
        Nhpdliuxvd123Label             matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
        Image4                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        TnghpLabel                     matlab.ui.control.Label
        TrngnhmLabel                   matlab.ui.control.Label
        GiaodinappLabel                matlab.ui.control.Label
        GiithiunhmLabel                matlab.ui.control.Label
        NisuyLabel                     matlab.ui.control.Label
        HiquyLabel                     matlab.ui.control.Label
        oHmLabel                       matlab.ui.control.Label
        NghimLabel                     matlab.ui.control.Label
        TchphnLabel                    matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        VMinhDuyLabel                  matlab.ui.control.Label
        Image_7                        matlab.ui.control.Image
        Label_5                        matlab.ui.control.Label
        NguynHingLabel                 matlab.ui.control.Label
        Image_6                        matlab.ui.control.Image
        Label_4                        matlab.ui.control.Label
        ThiLngGiaBoLabel               matlab.ui.control.Label
        Image_5                        matlab.ui.control.Image
        Label_3                        matlab.ui.control.Label
        LDngCngLabel                   matlab.ui.control.Label
        Image_4                        matlab.ui.control.Image
        Label_2                        matlab.ui.control.Label
        LcCnhLabel                     matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        LngGiaHoLabel                  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        % Phương pháp Hình thang
            function result = HinhThang(~, x, y, N)
                h = (x(end)-x(1))/N;
                sumf = 0;
                for i=1:N-1
                sumf = sumf + y(1+i);
                end
                result = (h/2)*(y(1)+y(end)+2*sumf);
            end

            % Phương pháp Simpson 1/3
            function result = Simpson13(~, x, y, N)
                if mod(N,2) ~= 0
                    error('N phải là số chia hết cho 2 khi áp dụng Simpson 1/3');
                end
                h = (x(end) - x(1))/N;
                sumf_chan = 0;
                sumf_le = 0;

                % Tính tổng các thành phần y ở vị trí chẵn
                for i=1:2:N-1
                    sumf_chan = sumf_chan + y(1+i);
                end

                % Tính tổng các thành phần y ở vị trí lẻ
                for i=2:2:N-1
                    sumf_le = sumf_le + y(1+i);
                end
                result = (h/3)*(y(1) + y(end) + 4*sumf_chan + 2*sumf_le);
            end

            % Phương pháp Simpson 3/8
            function result = Simpson38(~, x, y, N)
                if mod(N,3) ~= 0
                    error('N phải là số chia hết cho 3 khi áp dụng Simpson 3/8');
                end
                h = (x(end) - x(1)) / N;
                result = y(1) + y(end);
                for i = 2:3:N-1
                    result = result + 3 * (y(i) + y(i + 1));
                end
                for i = 4:3:N - 1
                    result = result + 2 * y(i);
                end
                result = result * 3 * h / 8;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThchinButton_2
        function ThucHienNoiSuyButton_2Pushed(app, event)
            % Đọc dữ liệu từ EditField
            x = str2num(app.Nhpdliuxvd123EditField.Value); %#ok<ST2NM>
            y = str2num(app.Nhpdliuyvd123EditField.Value); %#ok<ST2NM>
            x_interp = app.NhpgitrcnnisuyEditField.Value; % Giá trị cần nội suy
            
            % Kiểm tra tính hợp lệ của dữ liệu
            if isempty(x) || isempty(y) || length(x) ~= length(y)
                uialert(app.UIFigure, 'Dữ liệu x và y không hợp lệ hoặc không khớp nhau!', 'Lỗi');
                return;
            end
    
            % Xác định phương pháp nội suy từ ButtonGroup
            method = app.ChnphngphpnisuyButtonGroup.SelectedObject.Text;
        
            % Tạo đa thức nội suy
            syms X; % Biến đại diện cho x trong đa thức
            n = length(x);
            p = 0; % Khởi tạo đa thức
            
            switch method
                case 'Newton'
                    % Tính nội suy Newton
                    divided_diff = zeros(n, n);
                    divided_diff(:, 1) = y';
        
                    for j = 2:n
                        for i = j:n
                            divided_diff(i, j) = (divided_diff(i, j-1) - divided_diff(i-1, j-1)) / (x(i) - x(i-j+1));
                        end
                    end
        
                    term = 1;
                    p = divided_diff(1, 1);
                    for k = 2:n
                        term = term * (X - x(k-1));
                        p = p + divided_diff(k, k) * term;
                    end
                case 'Lagrange'
                    % Tính nội suy Lagrange
                    for i = 1:n
                        L = 1;
                        for j = 1:n
                            if i ~= j
                                L = L * (X - x(j)) / (x(i) - x(j));
                            end
                        end
                        p = p + y(i) * L;
                    end
            end
        
            % Tính và hiển thị kết quả nội suy tại điểm cụ thể
            y_interp = double(subs(p, x_interp));
            app.KtqunisuyEditField.Value = y_interp;
        
            % Hiển thị đa thức nội suy vào ô kết quả
            app.KtquathcnisuyEditField.Value = char(vpa(p, 4));
        
            % Vẽ đồ thị nội suy
            fplot(app.UIAxes2, p, [min(x)-1, max(x)+1], 'b-', 'LineWidth', 1.5); % Hàm nội suy
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'ro', 'MarkerSize', 8, 'LineWidth', 1.5); % Dữ liệu thực
            plot(app.UIAxes2, x_interp, y_interp, 'g*', 'MarkerSize', 10, 'LineWidth', 2); % Điểm nội suy
            hold(app.UIAxes2, 'off');
            
            % Cập nhật trục và nhãn
            title(app.UIAxes2, 'Nội suy Newton hoặc Lagrange');
            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            legend(app.UIAxes2, 'Hàm nội suy', 'Dữ liệu thực', 'Điểm nội suy', 'Location', 'Best');
        end

        % Button pushed function: ThucHienButton_5
        function ThucHienTichPhanButton_5Pushed(app, event)
           try
                % Nhập x, y, N
                x = str2num(app.NhapXEditField_4.Value);
                y = str2num(app.NhapYEditField_4.Value);
                N = str2num(app.NhapNEditField.Value);

                if isempty(x) && ~isempty(app.NhapHamEditField_2.Value)
                    f = app.NhapHamEditField_2.Value;
                    % Chuyển đổi toán tử cho phù hợp với mảng
                    f = strrep(f, '^', '.^');
                    f = strrep(f, '*', '.*');
                    f = strrep(f, '/', './');
                    f = str2func(['@(x)',f]);
                    Can = str2num(app.NhapCanEditField.Value);
                    x = linspace(Can(1), Can(2), N+1);
                    y = f(x);
                end

                if app.HinhThangButton.Value == 1
                    result = app.HinhThang(x,y,N);
                elseif app.Simpson13Button.Value == 1
                    result = app.Simpson13(x,y,N);
                elseif app.Simpson38Button.Value == 1
                    result = app.Simpson38(x,y,N);
                else 
                    error('Chưa chọn được phương pháp tính tích phân!');
                end

                % Hiển thị kết quả
                app.KetQuaEditField_2.Value = num2str(result);
                catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
           end
        end

        % Button pushed function: ThchinButton
        function ThchinButtonPushed(app, event)
                       % Lấy giá trị đầu vào từ giao diện
fx = app.NhpphngtrnhEditField.Value; % Hàm số
fp = app.NhpphngtrnhdngxgxdnhchophplpEditField.Value;
disp(['Hàm số nhập vào: ', fx]); % Hiển thị hàm số nhập vào
a = app.aEditField.Value; % Giá trị a
b = app.bEditField.Value; % Giá trị b
saiso = app.NhpsaischophpEditField.Value; % Sai số
method = app.ChnphngphptmButtonGroup.SelectedObject.Text; % Phương pháp được chọn
disp(['Khoảng [a, b]: ', num2str(a), ' - ', num2str(b)]); % Hiển thị khoảng [a, b]
disp(['Sai số cho phép: ', num2str(saiso)]); % Hiển thị sai số
% Switch Case để chọn phương pháp
switch method
    case 'Chia đôi'
        % Phương pháp chia đôi
        fxi = str2func(['@(x)', fx]);
        disp('Đang sử dụng phương pháp chia đôi...');
        if fxi(a) * fxi(b) >= 0
            error('Hàm không có nghiệm trong khoảng [a, b].');
        end
        n = 0;
        while true
            c = (a + b) / 2;
            disp(['Lần lặp thứ ', num2str(n+1), ', c = ', num2str(c)]); % Theo dõi giá trị c
            f_test = fxi(a) * fxi(c);
            if f_test == 0
                break;
            end
            if f_test < 0
                b = c;
            else
                a = c;
            end
            if abs(a - b) < saiso
                break;
            end
            n = n + 1;
        end
        x = c;

    case 'Lặp'
       % Phương pháp phép lặp
        fp_x = str2func(['@(x)', fp]);
        fp1 = diff(str2sym(fp));
        fp_daoham = matlabFunction(fp1);
        x0 = a;
        n = 0;
        
        disp('Đang sử dụng phương pháp phép lặp...');
        
        % Kiểm tra hội tụ
        for i = a:0.1:b
            k = fp_daoham(i);
            disp(['Giá trị đạo hàm tại ', num2str(i), ': ', num2str(k)]);
            if k >= 1
                error('Phương trình không hội tụ');
            end
        end

        while true
            x1 = fp_x(x0);
            disp(['Lần lặp thứ ', num2str(n+1), ', x1 = ', num2str(x1)]); % Theo dõi giá trị x1
            if abs(x1 - x0) < saiso
                break;
            end
            n = n + 1;
            x0 = x1;
        end
        x = x1;


    case 'Newton'
        % Phương pháp tiếp tuyến
        fxi = str2func(['@(x)', fx]);
        fxii = diff(str2sym(fx)); % Đạo hàm cấp 1
        fxiii = diff(diff(str2sym(fx))); % Đạo hàm cấp 2
        fxi_daoham = matlabFunction(fxii);
        fxi_daohamc2 = matlabFunction(fxiii);
        x0 = b;
        n = 0;

        disp('Đang sử dụng phương pháp tiếp tuyến...');
        
        % Kiểm tra hội tụ
        i = a:0.01:b;
        daohamcap1 = fxi_daoham(i);
        daohamcap2 = fxi_daohamc2(i);
        t1 = sign(daohamcap1);
        t2 = sign(daohamcap2);
        if any(diff(t1) ~= 0) || any(diff(t2) ~= 0)
            error('Không thỏa điều kiện hội tụ');
        end
        if fxi(x0) * fxi_daohamc2(x0) < 0
            error('Chọn lại x0 để 𝑓(x0) cùng dấu với 𝑓′′(x0)');
        end

        while true
            d = fxi_daoham(x0);
            disp(['Lần lặp thứ ', num2str(n+1), ', d = ', num2str(d)]); % Theo dõi giá trị đạo hàm
            if d == 0
                error('Đạo hàm bằng 0 nên không thể thực hiện');
            end
            x1 = x0 - fxi(x0) / d;
            disp(['x1 = ', num2str(x1)]); % Theo dõi giá trị x1
            if abs(x1 - x0) < saiso
                break;
            end
            n = n + 1;
            x0 = x1;

            if n > 100
                error('Chọn lại x0 để phương trình hội tụ nhanh hơn');
            end
        end
        x = x1;

    otherwise
        error('Phương pháp không hợp lệ');
end

% Hiển thị kết quả vào Edit Field
disp(['Kết quả nghiệm: ', num2str(x)]);
app.KtqunghimEditField.Value = round(x,4); % Hiển thị nghiệm
app.SlnlpEditField.Value = round(n,4); % Hiển thị số lần lặp

% Định nghĩa hàm vector hóa để phù hợp với fplot
ve = str2func(['@(x)', fx]);
ve = @(x) arrayfun(ve, x); % Vector hóa hàm số

% Vẽ đồ thị hàm số từ a đến b
disp('Vẽ đồ thị hàm số...');
fplot(ve, [a, b], 'Parent', app.UIAxes); 

hold(app.UIAxes, 'on');

% Đánh dấu nghiệm trên đồ thị
plot(app.UIAxes, x, ve(x), 'ro'); % Đánh dấu nghiệm bằng một điểm đỏ

hold(app.UIAxes, 'off');

        end

        % Button pushed function: ThchinButton_4
        function ThchinButton_4Pushed(app, event)
            z = app.NhpgitrcntnhohmEditField.Value;
            if double(app.Tchvonuchncchnhpdliuth2CheckBox.Value) == 0
                x = str2num(app.Nhpdliuxvd123EditField_3.Value);
                y = str2num(app.Nhpdliuyvd123EditField_3.Value);
            else
                h = app.NhpbchEditField.Value;
                fx = str2func(['@(x)',app.NhphmsEditField.Value]);
                x = [z-2*h z-h z z+h z+2*h];
                y = [fx(z-2*h) fx(z-h) fx(z) fx(z+h) fx(z+2*h)];
            end
            if numel(x) ~= numel(y)
                uialert(app.UIFigure, 'Kích thước hai mãng không khớp nhau', 'Lỗi');
                return;
            end
            h = x(2) - x(1);
            [kt, i] = ismember(z, x);
            if kt == false
                uialert(app.UIFigure, 'Giá trị cần tính đạo hàm không hợp lệ', 'Lỗi');
                return;
            end
            f = double(app.OhButton.Value);
            if double(app.TinButton.Value) == 1
                if f == 1
                    if (numel(x) - i) < 1
                        uialert(app.UIFigure, 'Không đủ dữ liệu để tính toán', 'Lỗi');
                        return;
                    end
                    ketqua = (-y(i) + y(i + 1))/h;
                else
                    if (numel(x) - i) < 2
                        uialert(app.UIFigure, 'Không đủ dữ liệu để tính toán', 'Lỗi');
                        return;
                    end
                    ketqua = (-3*y(i) + 4*y(i + 1) - y(i + 2))/(2*h);
                end
            elseif double(app.LiButton.Value) == 1
                if f == 1
                    if (i - 1) < 1
                        uialert(app.UIFigure, 'Không đủ dữ liệu để tính toán', 'Lỗi');
                        return;
                    end
                    ketqua = (y(i) - y(i - 1))/h;
                else
                    if (i - 1) < 2
                        uialert(app.UIFigure, 'Không đủ dữ liệu để tính toán', 'Lỗi');
                        return;
                    end
                    ketqua = (3*y(i) - 4*y(i - 1) + y(i - 2))/(2*h);
                end
            else
                if ((numel(x) - i) < 1) || ((i - 1) < 1)
                    uialert(app.UIFigure, 'Không đủ dữ liệu để tính toán', 'Lỗi');
                    return;
                end
                ketqua = (-y(i - 1) + y(i + 1))/(2*h);
            end
            app.KtquEditField.Value = ketqua;
        end

        % Button pushed function: tinhtoan
        function tinhtoanButtonPushed(app, event)
x = str2num(app.nhapx.Value); 
y = str2num(app.nhapy.Value);
if length(x) ~= length(y)
    uialert(app.UIFigure, 'Số phần tử của x và y phải bằng nhau!', 'Lỗi');
    return;
end
function coeffs = hoiquyTuyenTinh(x, y)
    n = length(x);
    sumx = sum(x);
    sumy = sum(y);
    sumxy = sum(x .* y);
    sumx2 = sum(x .^ 2);
    a1 = (n * sumxy - sumx * sumy) / (n * sumx2 - sumx^2);
    a0 = mean(y) - a1 * mean(x);
    coeffs = [a1, a0];
end
function coeffs = hoiquyHamMu(x, y)
    ln_y = log(y);
    lin_coeffs = hoiquyTuyenTinh(x, ln_y);
    a0 = exp(lin_coeffs(2));
    a1 = lin_coeffs(1);
    coeffs = [a0, a1];
end
function coeffs = hoiquyLogarit(x, y)
    ln_x = log(x);
    lin_coeffs = hoiquyTuyenTinh(ln_x, y);
    a0 = lin_coeffs(2);
    a1 = lin_coeffs(1);
    coeffs = [a1, a0];
end
if app.tuyentinh.Value
    coeffs = hoiquyTuyenTinh(x, y);
    app.ketqua.Value = sprintf('Hồi quy tuyến tính: y = %.4fx + %.4f', coeffs(1), coeffs(2));
elseif app.hammu.Value
    if any(y <= 0)
        uialert(app.UIFigure, 'Dữ liệu y phải lớn hơn 0 để sử dụng hồi quy hàm mũ.', 'Lỗi');
        return;
    end
    coeffs = hoiquyHamMu(x, y);
    app.ketqua.Value = sprintf('Hồi quy hàm mũ: y = %.4fe^{%.4fx}', coeffs(1), coeffs(2));
elseif app.Logarit.Value
    if any(x <= 0)
        uialert(app.UIFigure, 'Dữ liệu x phải lớn hơn 0 để sử dụng hồi quy logarit.', 'Lỗi');
        return;
    end
    coeffs = hoiquyLogarit(x, y);
    app.ketqua.Value = sprintf('Hồi quy logarit: y = %.4fln(x) + %.4f', coeffs(1), coeffs(2));
else
    uialert(app.UIFigure, 'Vui lòng chọn một phương pháp hồi quy.', 'Lỗi');
    return;
end
plot(app.dothi, x, y, 'o'); 
hold(app.dothi, 'on');
if app.tuyentinh.Value
    y_fit = coeffs(1) * x + coeffs(2);  
elseif app.hammu.Value
    y_fit = coeffs(1) * exp(coeffs(2) * x);  
elseif app.Logarit.Value
    y_fit = coeffs(1) * log(x) + coeffs(2);  
end
plot(app.dothi, x, y_fit, '-r'); 
hold(app.dothi, 'off');
title(app.dothi, 'Hồi Quy');
xlabel(app.dothi, 'X');
ylabel(app.dothi, 'Y');
x_req = app.reqnum.Value;  
if isnan(x_req) || ~isfinite(x_req)
    uialert(app.UIFigure, 'Giá trị nhập phải là số hợp lệ.', 'Lỗi');
    return;
end
if app.tuyentinh.Value
    y_pred = coeffs(1) * x_req + coeffs(2);  
elseif app.hammu.Value
    if x_req <= 0
        uialert(app.UIFigure, 'Giá trị cần dự đoán phải lớn hơn 0 với phương pháp hàm mũ.', 'Lỗi');
        return;
    end
    y_pred = coeffs(1) * exp(coeffs(2) * x_req);  
elseif app.Logarit.Value
    if x_req <= 0
        uialert(app.UIFigure, 'Giá trị cần dự đoán phải lớn hơn 0 với phương pháp logarit.', 'Lỗi');
        return;
    end
    y_pred = coeffs(1) * log(x_req) + coeffs(2);  
else
    y_pred = NaN;  
end
if isnan(y_pred) || ~isfinite(y_pred)
    uialert(app.UIFigure, 'Kết quả dự đoán không hợp lệ.', 'Lỗi');
else
    app.kqreq.Value = y_pred;  
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimabLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimabLabel.Text = 'Nhập khoảng phân ly nghiệm [a, b] ';
            app.aLabel = uilabel(app.NghimTab);
            app.aLabel.Text = 'a :';
            app.aEditField = uieditfield(app.NghimTab, 'numeric');
            app.bLabel = uilabel(app.NghimTab);
            app.bLabel.Text = 'b :';
            app.bEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphptmButtonGroup = uibuttongroup(app.NghimTab);
            app.ChnphngphptmButtonGroup.Title = 'Chọn phương pháp tìm';
            app.ChiaiButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.ChiaiButton.Text = 'Chia đôi';
            app.ChiaiButton.Value = true;
            app.LpButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.LpButton.Text = 'Lặp';
            app.NewtonButton = uitogglebutton(app.ChnphngphptmButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpLabel = uilabel(app.NghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ThchinButton = uibutton(app.NghimTab, 'push');
            app.ThchinButton.ButtonPushedFcn = createCallbackFcn(app, @ThchinButtonPushed, true);
            app.ThchinButton.Text = 'Thực hiện';
            app.NhpphngtrnhdngxgxdnhchophplpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhdngxgxdnhchophplpEditFieldLabel.Text = {'Nhập phương trình dạng '; 'x = g(x) dành cho phép lặp'};
            app.NhpphngtrnhdngxgxdnhchophplpEditField = uieditfield(app.NghimTab, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Hàm số nội suy và giá trị thực')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.Nhpdliuxvd123EditFieldLabel = uilabel(app.NisuyTab);
            app.Nhpdliuxvd123EditFieldLabel.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.Nhpdliuxvd123EditField = uieditfield(app.NisuyTab, 'text');
            app.Nhpdliuyvd123EditFieldLabel = uilabel(app.NisuyTab);
            app.Nhpdliuyvd123EditFieldLabel.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.Nhpdliuyvd123EditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyButtonGroup = uibuttongroup(app.NisuyTab);
            app.ChnphngphpnisuyButtonGroup.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton_2 = uitogglebutton(app.ChnphngphpnisuyButtonGroup);
            app.NewtonButton_2.Text = 'Newton';
            app.NewtonButton_2.Value = true;
            app.LagrangeButton = uitogglebutton(app.ChnphngphpnisuyButtonGroup);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ThchinButton_2 = uibutton(app.NisuyTab, 'push');
            app.ThchinButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThucHienNoiSuyButton_2Pushed, true);
            app.ThchinButton_2.Text = 'Thực hiện';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.dothi = uiaxes(app.HiquyTab);
            title(app.dothi, 'Hàm số hồi quy và giá trị thực')
            xlabel(app.dothi, 'x')
            ylabel(app.dothi, 'y')
            zlabel(app.dothi, 'Z')
            app.ChnphngphphiquyButtonGroup = uibuttongroup(app.HiquyTab);
            app.ChnphngphphiquyButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.tuyentinh = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.tuyentinh.Text = 'Tuyến tính';
            app.tuyentinh.Value = true;
            app.hammu = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.hammu.Text = 'Hàm mũ';
            app.Logarit = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.Logarit.Text = 'Logarit';
            app.Nhpdliuxvd123EditField_2Label = uilabel(app.HiquyTab);
            app.Nhpdliuxvd123EditField_2Label.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.nhapx = uieditfield(app.HiquyTab, 'text');
            app.Nhpdliuyvd123EditField_2Label = uilabel(app.HiquyTab);
            app.Nhpdliuyvd123EditField_2Label.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.nhapy = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.ketqua = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.reqnum = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.kqreq = uieditfield(app.HiquyTab, 'numeric');
            app.tinhtoan = uibutton(app.HiquyTab, 'push');
            app.tinhtoan.ButtonPushedFcn = createCallbackFcn(app, @tinhtoanButtonPushed, true);
            app.tinhtoan.Text = 'Thực hiện';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.Nhpdliuxvd123EditField_3Label = uilabel(app.ohmTab);
            app.Nhpdliuxvd123EditField_3Label.Text = 'Nhập dữ liệu x (vd: 1, 2, 3)';
            app.Nhpdliuxvd123EditField_3 = uieditfield(app.ohmTab, 'text');
            app.Nhpdliuyvd123EditField_3Label = uilabel(app.ohmTab);
            app.Nhpdliuyvd123EditField_3Label.Text = 'Nhập dữ liệu y (vd: 1, 2, 3)';
            app.Nhpdliuyvd123EditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'numeric');
            app.Tchvonuchncchnhpdliuth2CheckBox = uicheckbox(app.ohmTab);
            app.Tchvonuchncchnhpdliuth2CheckBox.Text = 'Tích vào nếu chọn cách nhập dữ liệu thứ 2';
            app.ChngitrsaisButtonGroup = uibuttongroup(app.ohmTab);
            app.ChngitrsaisButtonGroup.Title = 'Chọn giá trị sai số';
            app.OhButton = uitogglebutton(app.ChngitrsaisButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uitogglebutton(app.ChngitrsaisButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.ChnphngphpohmButtonGroup = uibuttongroup(app.ohmTab);
            app.ChnphngphpohmButtonGroup.Title = 'Chọn phương pháp đạo hàm ';
            app.TinButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.TinButton.Text = 'Tiến';
            app.TinButton.Value = true;
            app.LiButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.LiButton.Text = 'Lùi';
            app.TrungtmButton = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.TrungtmButton.Text = 'Trung tâm';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.NhpgitrcntnhohmEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.ThchinButton_4 = uibutton(app.ohmTab, 'push');
            app.ThchinButton_4.ButtonPushedFcn = createCallbackFcn(app, @ThchinButton_4Pushed, true);
            app.ThchinButton_4.Text = 'Thực hiện';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.Nhpdliuxvd123Label = uilabel(app.TichPhanTab);
            app.Nhpdliuxvd123Label.Text = 'Nhập dữ liệu x (vd: 1 2 3)';
            app.NhapXEditField_4 = uieditfield(app.TichPhanTab, 'text');
            app.Nhpdliuyvd123Label = uilabel(app.TichPhanTab);
            app.Nhpdliuyvd123Label.Text = 'Nhập dữ liệu y (vd: 1 2 3)';
            app.NhapYEditField_4 = uieditfield(app.TichPhanTab, 'text');
            app.HocLabel_2 = uilabel(app.TichPhanTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhphmsEditField_2Label = uilabel(app.TichPhanTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.NhapHamEditField_2 = uieditfield(app.TichPhanTab, 'text');
            app.NhpcntnhTchphnvd15Label = uilabel(app.TichPhanTab);
            app.NhpcntnhTchphnvd15Label.Text = 'Nhập cận để tính Tích phân (vd: 1 5)';
            app.NhapCanEditField = uieditfield(app.TichPhanTab, 'text');
            app.ThucHienButton_5 = uibutton(app.TichPhanTab, 'push');
            app.ThucHienButton_5.ButtonPushedFcn = createCallbackFcn(app, @ThucHienTichPhanButton_5Pushed, true);
            app.ThucHienButton_5.Text = 'Thực hiện';
            app.ChonPPButtonGroup = uibuttongroup(app.TichPhanTab);
            app.ChonPPButtonGroup.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThangButton = uiradiobutton(app.ChonPPButtonGroup);
            app.HinhThangButton.Text = 'Hình thang';
            app.HinhThangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.ChonPPButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.ChonPPButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpNEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapNEditField = uieditfield(app.TichPhanTab, 'text');
            app.Image = uiimage(app.TichPhanTab);
            app.Image.ImageSource = 'maxresdefault.jpg';
            app.KtquLabel = uilabel(app.TichPhanTab);
            app.KtquLabel.Text = 'Kết quả';
            app.KetQuaEditField_2 = uieditfield(app.TichPhanTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image_2 = uiimage(app.GiithiunhmTab);
            app.Image_2.ImageSource = 'Screenshot 2024-12-20 064218.png';
            app.LngGiaHoLabel = uilabel(app.GiithiunhmTab);
            app.LngGiaHoLabel.Text = 'Lương Gia Hào';
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = '22207024';
            app.Image_3 = uiimage(app.GiithiunhmTab);
            app.Image_3.ImageSource = 'Screenshot 2024-12-20 064401.png';
            app.LcCnhLabel = uilabel(app.GiithiunhmTab);
            app.LcCnhLabel.Text = 'Lê Đắc Cảnh';
            app.Label_2 = uilabel(app.GiithiunhmTab);
            app.Label_2.Text = '22207010';
            app.Image_4 = uiimage(app.GiithiunhmTab);
            app.Image_4.ImageSource = 'Screenshot 2024-12-20 064128.png';
            app.LDngCngLabel = uilabel(app.GiithiunhmTab);
            app.LDngCngLabel.Text = 'Lê Dương Công';
            app.Label_3 = uilabel(app.GiithiunhmTab);
            app.Label_3.Text = '22207024';
            app.Image_5 = uiimage(app.GiithiunhmTab);
            app.Image_5.ImageSource = 'Screenshot 2024-12-20 064018.png';
            app.ThiLngGiaBoLabel = uilabel(app.GiithiunhmTab);
            app.ThiLngGiaBoLabel.Text = 'Thái Lương Gia Bảo';
            app.Label_4 = uilabel(app.GiithiunhmTab);
            app.Label_4.Text = '22207008';
            app.Image_6 = uiimage(app.GiithiunhmTab);
            app.Image_6.ImageSource = 'Screenshot 2024-12-20 064314.png';
            app.NguynHingLabel = uilabel(app.GiithiunhmTab);
            app.NguynHingLabel.Text = 'Nguyễn Hải Đăng';
            app.Label_5 = uilabel(app.GiithiunhmTab);
            app.Label_5.Text = '22207014';
            app.Image_7 = uiimage(app.GiithiunhmTab);
            app.Image_7.ImageSource = 'Screenshot 2024-12-20 063936.png';
            app.VMinhDuyLabel = uilabel(app.GiithiunhmTab);
            app.VMinhDuyLabel.Text = 'Võ Minh Duy';
            app.Label_6 = uilabel(app.GiithiunhmTab);
            app.Label_6.Text = '22207020';
            app.TchphnLabel = uilabel(app.GiithiunhmTab);
            app.TchphnLabel.Text = 'Tích phân';
            app.NghimLabel = uilabel(app.GiithiunhmTab);
            app.NghimLabel.Text = 'Nghiệm';
            app.oHmLabel = uilabel(app.GiithiunhmTab);
            app.oHmLabel.Text = 'Đạo Hàm';
            app.HiquyLabel = uilabel(app.GiithiunhmTab);
            app.HiquyLabel.Text = 'Hồi quy';
            app.NisuyLabel = uilabel(app.GiithiunhmTab);
            app.NisuyLabel.Text = 'Nội suy';
            app.GiithiunhmLabel = uilabel(app.GiithiunhmTab);
            app.GiithiunhmLabel.Text = 'Giới thiệu nhóm';
            app.GiaodinappLabel = uilabel(app.GiithiunhmTab);
            app.GiaodinappLabel.Text = 'Giao diện app';
            app.TrngnhmLabel = uilabel(app.GiithiunhmTab);
            app.TrngnhmLabel.Text = '(Trưởng nhóm)';
            app.TnghpLabel = uilabel(app.GiithiunhmTab);
            app.TnghpLabel.Text = 'Tổng hợp';
            app.Image2 = uiimage(app.GiithiunhmTab);
            app.Image2.ImageSource = 'Screenshot 2024-12-20 063311.png';
            app.Image2_2 = uiimage(app.GiithiunhmTab);
            app.Image2_2.ImageSource = 'Screenshot 2024-12-20 063354.png';
            app.Image3 = uiimage(app.GiithiunhmTab);
            app.Image3.ImageSource = 'Screenshot 2024-12-20 063541.png';
            app.Image3_2 = uiimage(app.GiithiunhmTab);
            app.Image3_2.ImageSource = 'Screenshot 2024-12-20 063716.png';
            app.Image5 = uiimage(app.GiithiunhmTab);
            app.Image5.ImageSource = 'Screenshot 2024-12-20 064641.png';
            app.Image4 = uiimage(app.GiithiunhmTab);
            app.Image4.ImageSource = 'Screenshot 2024-12-20 064520.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doan_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end